classdef CheckForUpdates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoftwareUpdateUIFigure  matlab.ui.Figure
        UpdateButton            matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        Status                  matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        versionText             matlab.ui.control.Label
    end

    properties (Access = private)
        CallingApp  % Main app
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
            % Update the version label
            % Check the latest release on GitHub
            try
                details = webread('https://github.com/ACRF-Image-X-Institute/contour-alignment-tool/releases/latest');
                checkVersion = strfind(details,sprintf('Contour Alignment Tool v%s',app.CallingApp.version));
                
                if checkVersion
                    app.versionText.Text = sprintf('You are on the latest version %s.',app.CallingApp.version);
                else
                    checkVersion = strfind(details,'Contour Alignment Tool v');
                    version = details(checkVersion(1)+24:checkVersion(1)+28);
                    
                    app.Status.Text = 'Update Available!';
                    app.versionText.Text = sprintf('New version %s is available. You have %s.',version,app.CallingApp.version);
                    app.CloseButton.Text = 'Later';
                    app.UpdateButton.Visible = 'on';
                end
            catch
                app.Status.Text = 'Unable to retrieve latest version';
                app.versionText.Text = sprintf('You have version %s.',app.CallingApp.version);
            end
            
            % Set the location of the window
            left = app.CallingApp.ContourAlignmentToolUIFigure.Position(1)+app.CallingApp.ContourAlignmentToolUIFigure.Position(3)/2-app.SoftwareUpdateUIFigure.Position(3)/2;
            bottom = app.CallingApp.ContourAlignmentToolUIFigure.Position(2)+app.CallingApp.ContourAlignmentToolUIFigure.Position(4)/2-app.SoftwareUpdateUIFigure.Position(4)/2;
            movegui(app.SoftwareUpdateUIFigure,[left bottom]);
            
        end

        % Callback function: CloseButton, SoftwareUpdateUIFigure
        function SoftwareUpdateUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            web('https://github.com/ACRF-Image-X-Institute/contour-alignment-tool/releases/latest')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoftwareUpdateUIFigure = uifigure('Visible', 'off');
            app.SoftwareUpdateUIFigure.Name = 'Update';
            app.SoftwareUpdateUIFigure.Icon = 'icon_48.png';
            app.SoftwareUpdateUIFigure.CloseRequestFcn = createCallbackFcn(app, @SoftwareUpdateUIFigureCloseRequest, true);
            app.versionText = uilabel(app.SoftwareUpdateUIFigure);
            app.versionText.Text = 'You are on the latest version';
            app.TextArea = uitextarea(app.SoftwareUpdateUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Release notes of 1.3.2'; 'Changes to existing features'; '- Move reset contour button to center of arrows.'; '- Added export location to dialogue box upon export completion.'; 'Added features'; '- Added structure centroid coordinates to the alignment panel.'; '- Added check box to enable disable click and drag.'; 'Resolved issues'; '- Resolved issue of persistent cross hair arrow following ROI selection.'; ''; 'Release notes of 1.3.1'; 'Changes to existing features'; '- Changed layout of the components to improve display when resizing the window.'; 'Resolved issues'; '- Resolved issue preventing all structures from being listed in the drop down.'};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
            app.Status = uilabel(app.SoftwareUpdateUIFigure);
            app.Status.Text = 'You are up to date';
            app.CloseButton = uibutton(app.SoftwareUpdateUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @SoftwareUpdateUIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.UpdateButton = uibutton(app.SoftwareUpdateUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Visible = 'off';
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.SoftwareUpdateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckForUpdates(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SoftwareUpdateUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SoftwareUpdateUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoftwareUpdateUIFigure)
        end
    end
end