classdef DRRviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        drrUIFigure              matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ContrastAdjustmentPanel  matlab.ui.container.Panel
        ROIselectionButton       matlab.ui.control.StateButton
        FinecontrolButton        matlab.ui.control.StateButton
        AutoadjustmentButton     matlab.ui.control.StateButton
        UpperSlider              matlab.ui.control.Slider
        LowerSlider              matlab.ui.control.Slider
        Histogram                matlab.ui.control.UIAxes
        ROIselectionLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp  % Main app
        width
        height
        checkers
        I
    end
    
    methods (Access = public)
        
        % Update the DRR viewer
        function updateDRR(app)

            drrMask = permute(app.CallingApp.originalMasks(:,:,app.CallingApp.currentFrame),[2 1 3]);
            drrMask = imbinarize(drrMask);
            drrMaskOutline = boundarymask(drrMask);
            
            app.I = app.CallingApp.DRR;
                
            % Set the contrast of the projection
            if app.AutoadjustmentButton.Value
                app.LowerSlider.Value = prctile(app.I(:),0.5);
                app.UpperSlider.Value = prctile(app.I(:),95);

                histogram(app.Histogram,app.I,50);  
                val1 = app.LowerSlider.Value;
                val2 = app.UpperSlider.Value;
                
            elseif app.FinecontrolButton.Value
                lower = prctile(app.I(:),0.5);
                upper = prctile(app.I(:),95);

                histogram(app.Histogram,app.I,50, 'BinLimits',[lower,upper]);  
                val1 = app.LowerSlider.Value * (upper-lower)+lower;
                val2 = app.UpperSlider.Value * (upper-lower)+lower;
        
            else
                histogram(app.Histogram,app.I,50);  
                val1 = app.LowerSlider.Value;
                val2 = app.UpperSlider.Value;
            end
                
            % Set the width of the DRR display
            if app.UIAxes.Position(3)/app.UIAxes.Position(4) <= app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1)
                app.width = app.UIAxes.Position(3);
                app.height = app.UIAxes.Position(3)/(app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1));
            else
                app.width = app.UIAxes.Position(4)*(app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1));
                app.height = app.UIAxes.Position(4);
            end
                
            % Display the DRR
            imshow(app.I,[min([val1,val2])*max(app.I(:)) max([val1,val2])*max(app.I(:))],'Parent',app.UIAxes,'XData', [1 app.width],'YData', [1 app.height]);
            app.UIAxes.XLim = [1 app.width];
            app.UIAxes.YLim = [1 app.height];
            
            % Display the original contour
            hold(app.UIAxes,'on')
                maskimage = imshow(app.checkers,'Parent',app.UIAxes,'XData', [1 app.width],'YData', [1 app.height]);
            hold(app.UIAxes,'off')
            set(maskimage, 'AlphaData', drrMaskOutline) 
            
            % Display the current contour
            hold(app.UIAxes,'on')
                maskimage = imshow(app.CallingApp.colour,'Parent',app.UIAxes,'XData', [1 app.width],'YData', [1 app.height]);
            hold(app.UIAxes,'off')
            if app.CallingApp.ContourFillMenu.Checked
                set(maskimage, 'AlphaData', app.CallingApp.Mask*0.25) 
            else
                set(maskimage, 'AlphaData', app.CallingApp.MaskOutline) 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
            % Set the location of the window
            left = app.CallingApp.ContourAlignmentToolUIFigure.Position(1)+app.CallingApp.ContourAlignmentToolUIFigure.Position(3)/2-app.drrUIFigure.Position(3)/2;
            bottom = app.CallingApp.ContourAlignmentToolUIFigure.Position(2)+app.CallingApp.ContourAlignmentToolUIFigure.Position(4)/2-app.drrUIFigure.Position(4)/2;
            movegui(app.drrUIFigure,[left bottom]);
            
            % Disable interactivity
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.Histogram)
            
            % Create the image used for the original contour position
            % border
            app.checkers = checkerboard(4,app.CallingApp.imageSize(1)/8,app.CallingApp.imageSize(2)/8)>0;
            app.checkers = double(cat(3,app.checkers,app.checkers,app.checkers)).*cat(3, ones(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)), zeros(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)), zeros(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)));
        end

        % Close request function: drrUIFigure
        function drrUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: AutoadjustmentButton
        function AutoadjustmentButtonValueChanged(app, event)
            % Update the contrast automatically
            app.FinecontrolButton.Value = 0;
            updateDRR(app)
        end

        % Value changed function: FinecontrolButton
        function FinecontrolButtonValueChanged(app, event)
            % Update the contrast using fine control
            if app.FinecontrolButton.Value
                app.AutoadjustmentButton.Value = 0;
                app.LowerSlider.Value = 0;
                app.UpperSlider.Value = 1;
                updateDRR(app)

            else
                updateDRR(app)
                app.AutoadjustmentButton.Enable = 'on';
            end
        end

        % Value changed function: ROIselectionButton
        function ROIselectionButtonValueChanged(app, event)
            % Allow the user to select an ROI for the contrast
            app.AutoadjustmentButton.Value = 0;
            app.FinecontrolButton.Value = 0;
            
            app.ROIselectionLabel.Visible = 'on';
            
            ROI = drawrectangle(app.UIAxes);
            ROI.Position(1) = round(ROI.Position(1)/app.width*app.CallingApp.imageSize(2));
            ROI.Position(3) = round(ROI.Position(3)/app.width*app.CallingApp.imageSize(2));
            ROI.Position(2) = round(ROI.Position(2)/app.height*app.CallingApp.imageSize(1));
            ROI.Position(4) = round(ROI.Position(4)/app.height*app.CallingApp.imageSize(1));
            
            app.LowerSlider.Value = min(min(app.I(ROI.Position(2):ROI.Position(2)+ROI.Position(4),...
                ROI.Position(1):ROI.Position(1)+ROI.Position(3))));
            app.UpperSlider.Value = max(max(app.I(ROI.Position(2):ROI.Position(2)+ROI.Position(4),...
                ROI.Position(1):ROI.Position(1)+ROI.Position(3))));
            
            delete(ROI)
            
            updateDRR(app)
            app.ROIselectionButton.Value = 0;
            app.ROIselectionLabel.Visible = 'off';
        end

        % Value changed function: LowerSlider, UpperSlider
        function UpperSliderValueChanging(app, event)
            % Update the contrast if the slider is changed
            if ~app.FinecontrolButton.Value
                app.AutoadjustmentButton.Value = 0;
            end
            updateDRR(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.drrUIFigure = uifigure('Visible', 'off');
            app.drrUIFigure.Name = 'DRR Viewer';
            app.drrUIFigure.Icon = 'icon_48.png';
            app.drrUIFigure.CloseRequestFcn = createCallbackFcn(app, @drrUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.drrUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.ROIselectionLabel = uilabel(app.GridLayout);
            app.ROIselectionLabel.Visible = 'off';
            app.ROIselectionLabel.Text = 'Click and drag the mouse to draw the ROI for auto-contrast adjustment ';
            app.ContrastAdjustmentPanel = uipanel(app.GridLayout);
            app.ContrastAdjustmentPanel.Title = 'Contrast Adjustment';
            app.Histogram = uiaxes(app.ContrastAdjustmentPanel);
            app.Histogram.XTick = [];
            app.Histogram.XTickLabel = '';
            app.Histogram.YTick = [];
            app.Histogram.YTickLabel = '';
            app.Histogram.Visible = 'off';
            app.LowerSlider = uislider(app.ContrastAdjustmentPanel);
            app.LowerSlider.Limits = [0 1];
            app.LowerSlider.MajorTicks = [];
            app.LowerSlider.ValueChangedFcn = createCallbackFcn(app, @UpperSliderValueChanging, true);
            app.LowerSlider.MinorTicks = [];
            app.UpperSlider = uislider(app.ContrastAdjustmentPanel);
            app.UpperSlider.Limits = [0 1];
            app.UpperSlider.MajorTicks = [];
            app.UpperSlider.ValueChangedFcn = createCallbackFcn(app, @UpperSliderValueChanging, true);
            app.UpperSlider.MinorTicks = [];
            app.UpperSlider.Value = 1;
            app.AutoadjustmentButton = uibutton(app.ContrastAdjustmentPanel, 'state');
            app.AutoadjustmentButton.ValueChangedFcn = createCallbackFcn(app, @AutoadjustmentButtonValueChanged, true);
            app.AutoadjustmentButton.Text = 'Auto adjustment';
            app.AutoadjustmentButton.Value = true;
            app.FinecontrolButton = uibutton(app.ContrastAdjustmentPanel, 'state');
            app.FinecontrolButton.ValueChangedFcn = createCallbackFcn(app, @FinecontrolButtonValueChanged, true);
            app.FinecontrolButton.Text = {'Fine '; 'control'};
            app.ROIselectionButton = uibutton(app.ContrastAdjustmentPanel, 'state');
            app.ROIselectionButton.ValueChangedFcn = createCallbackFcn(app, @ROIselectionButtonValueChanged, true);
            app.ROIselectionButton.Text = 'ROI selection';
            % Show the figure after all components are created
            app.drrUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRRviewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.drrUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.drrUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.drrUIFigure)
        end
    end
end